classdef ICK_SENSORS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label3                        matlab.ui.control.Label
        WIADOMOCSTEROWANIE2EditField  matlab.ui.control.EditField
        WIADOMOCSTEROWANIE2EditFieldLabel  matlab.ui.control.Label
        WIADOMOCSTEROWANIE1EditField  matlab.ui.control.EditField
        WIADOMOCSTEROWANIE1EditFieldLabel  matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ADRESURIEditField             matlab.ui.control.EditField
        ADRESURIEditFieldLabel        matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        KOLOR2MAXEditField            matlab.ui.control.NumericEditField
        KOLOR2MAXEditFieldLabel       matlab.ui.control.Label
        KOLOR2MINEditField            matlab.ui.control.NumericEditField
        KOLOR2MINEditFieldLabel       matlab.ui.control.Label
        KOLOR1MINEditField            matlab.ui.control.NumericEditField
        KOLOR1MINEditFieldLabel       matlab.ui.control.Label
        KOLOR1MAXEditField            matlab.ui.control.NumericEditField
        KOLOR1MAXEditFieldLabel       matlab.ui.control.Label
        DEADZONEEditField             matlab.ui.control.NumericEditField
        DEADZONEEditFieldLabel        matlab.ui.control.Label
        KAMERAButton                  matlab.ui.control.Button
        ORIENTACJAButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ORIENTACJAButton
        function ORIENTACJAButtonPushed(app, event)
           
                m=mobiledev;
            control=1;
            
            while true
                pitch=-m.Orientation(2);
                if pitch>app.DEADZONEEditField.Value
                    control=1;
                end
                if pitch<-app.DEADZONEEditField.Value
                    control=-1;
                end
                control
                app.Label.Text=control;
                app.Label2.Text=pitch;
                pause(1)
            end
        end

        % Button pushed function: KAMERAButton
        function KAMERAButtonPushed(app, event)
            if exist("m","var")==0
                m=mobiledev;
            
            end
            if exist("c","var")==0
                c=camera(m,"back");
            end
            
            while true
                I_camera=snapshot(c,'immediate');
                I_hsv=rgb2hsv(I_camera);
                Hue=I_hsv(:,:,1)*360;
                red= Hue < app.KOLOR1MAXEditField.Value | Hue>app.KOLOR1MINEditField.Value;
                cyan= Hue >app.KOLOR2MAXEditField.Value & Hue<app.KOLOR2MINEditField.Value;
                red_sum=sum(sum(red))+0.01;
                cyan_sum=sum(sum(cyan))+0.01;
                ratio=red_sum/cyan_sum
                if ratio>1
                    control=1
                else
                    control=-1    
                end
                app.Label.Text=control;
                app.Label3.Text=ratio;
                pause(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ORIENTACJAButton = uibutton(app.UIFigure, 'push');
            app.ORIENTACJAButton.ButtonPushedFcn = createCallbackFcn(app, @ORIENTACJAButtonPushed, true);
            app.ORIENTACJAButton.Text = 'ORIENTACJA';
            app.KAMERAButton = uibutton(app.UIFigure, 'push');
            app.KAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @KAMERAButtonPushed, true);
            app.KAMERAButton.Text = 'KAMERA';
            app.DEADZONEEditFieldLabel = uilabel(app.UIFigure);
            app.DEADZONEEditFieldLabel.Text = 'DEAD-ZONE';
            app.DEADZONEEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEADZONEEditField.Value = 10;
            app.KOLOR1MAXEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR1MAXEditFieldLabel.Text = 'KOLOR1-MAX';
            app.KOLOR1MAXEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR1MAXEditField.Value = 60;
            app.KOLOR1MINEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR1MINEditFieldLabel.Text = 'KOLOR1-MIN';
            app.KOLOR1MINEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR1MINEditField.Value = 300;
            app.KOLOR2MINEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR2MINEditFieldLabel.Text = 'KOLOR2-MIN';
            app.KOLOR2MINEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR2MINEditField.Value = 120;
            app.KOLOR2MAXEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR2MAXEditFieldLabel.Text = 'KOLOR2-MAX';
            app.KOLOR2MAXEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR2MAXEditField.Value = 240;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HueScale.png');
            app.ADRESURIEditFieldLabel = uilabel(app.UIFigure);
            app.ADRESURIEditFieldLabel.Text = 'ADRES URI';
            app.ADRESURIEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.WIADOMOCSTEROWANIE1EditFieldLabel = uilabel(app.UIFigure);
            app.WIADOMOCSTEROWANIE1EditFieldLabel.Text = 'WIADOMOŚC STEROWANIE 1';
            app.WIADOMOCSTEROWANIE1EditField = uieditfield(app.UIFigure, 'text');
            app.WIADOMOCSTEROWANIE2EditFieldLabel = uilabel(app.UIFigure);
            app.WIADOMOCSTEROWANIE2EditFieldLabel.Text = 'WIADOMOŚC STEROWANIE 2';
            app.WIADOMOCSTEROWANIE2EditField = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICK_SENSORS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end